classdef Funhouse_Filters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FunhouseFiltersUIFigure         matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        SelectImageSourceDropDown       matlab.ui.control.DropDown
        SelectImageSourceDropDownLabel  matlab.ui.control.Label
        Complexfilters                  matlab.ui.control.DropDown
        ComplexFiltersLabel             matlab.ui.control.Label
        TypesDropDown                   matlab.ui.control.DropDown
        TypesDropDownLabel              matlab.ui.control.Label
        KvalueSlider                    matlab.ui.control.Slider
        KvalueSliderLabel               matlab.ui.control.Label
        SaveImageButton                 matlab.ui.control.Button
        SelectSimpleFiltersLabel        matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        SimpleImageFiltersDropDown      matlab.ui.control.DropDown
        TakeaPhotoButton                matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        cam % Webcam
        inImg
        % imagefiles = {"Boy.jpg";"Girl.jpg";"Man_1.jpg";"Man_2.jpg";"Woman_1.jpg";"Woman_2.jpg"};
        % imlabels = {'Boy';'Girl';'Man_1';'Man_2';'Woman_1';'Woman_2'};
        wlist
        outImg
        photoTimer
        photoTaken=false
        isClosing=false % flag to indicate the app is closing
    end
    properties (Access = public)
        img % Webcam image

    end

    methods (Access = private)


        % Edge function
        function outImg = edgeeffect(app)

            app.inImg = im2gray(app.inImg);

            type  = app.TypesDropDown.Value;
            outImg=edge(app.inImg,type);

        end

        %Barrel function
        function outImg = barrel(app)
            a = app.KvalueSlider.Value * 1e-5;
            [nrows,ncols,~] = size(app.inImg);
            [xi,yi] = meshgrid(1:ncols,1:nrows);
            imid = round(size(app.inImg,2)/2); % Find index of middle element

            xt = xi(:) - imid;
            yt = yi(:) - imid;
            [theta,r] = cart2pol(xt,yt);
            s = r + a*r.^3;
            [ut,vt] = pol2cart(theta,s);
            u = reshape(ut,size(xi)) + imid;
            v = reshape(vt,size(yi)) + imid;
            resamp = makeresampler('linear','fill');
            tmap_B = cat(3,u,v);
            outImg = tformarray(app.inImg,[],resamp,[2 1],[1 2],[],tmap_B,.3);


        end

        %Blur function
        function outImg = blur(app)

            type  = app.TypesDropDown.Value;
            se = strel('disk',10);
            switch type
                case 'open'
                    outImg = imopen(app.inImg, se);
                case 'close'
                    outImg = imclose(app.inImg, se);
                case 'dilate'
                    outImg = imdilate(app.inImg, se);

            end

        end

        %Pencil function
        function outImg = pencil(app)
            type  = app.TypesDropDown.Value;
            grayImg = rgb2gray(app.inImg);

            if strcmpi(type, 'Light')
                h2 = [ 1  1 1;
                    1 -8 1;
                    1  1 1];
            elseif strcmpi(type, 'Dark')
                h2 =   [1 1  1  1 1 1;
                    1 1  1  1 1 1;
                    1 1 -8 -8 1 1;
                    1 1 -8 -8 1 1;
                    1 1  1  1 1 1;
                    1 1  1  1 1 1];
            end

            outImg = 255 - imfilter( grayImg, h2, 'conv');
        end
        % Pincushion function
        function outImg = pincushion(app)

            a = app.KvalueSlider.Value* -1e-6;
            [nrows,ncols,~] = size(app.inImg);
            [xi,yi] = meshgrid(1:ncols,1:nrows);
            imid = round(size(app.inImg,2)/2); % Find index of middle element
            xt = xi(:) - imid;
            yt = yi(:) - imid;
            [theta,r] = cart2pol(xt,yt);

            s = r + a*r.^3;
            [ut,vt] = pol2cart(theta,s);
            u = reshape(ut,size(xi)) + imid;
            resamp = makeresampler('linear','fill');
            v = reshape(vt,size(yi)) + imid;
            tmap_B = cat(3,u,v);
            outImg = tformarray(app.inImg,[],resamp,[2 1],[1 2],[],tmap_B,.3);

        end


        % Scale image to fit axes function
        function scaleddisp(~,axesHandle,imag)

            % Check if the provided axes handle is valid
            if ishandle(axesHandle)
                % Display the image and get handle
                I = imshow(imag, 'Parent', axesHandle);

                % Get the size of the specified axes
                axesPosition = axesHandle.Position; % [left bottom width height]
                axesWidth = axesPosition(3);
                axesHeight = axesPosition(4);

                % Get the size of the image
                [imgHeight, imgWidth, ~] = size(imag);

                % Calculate the aspect ratios
                imageAspectRatio = imgWidth / imgHeight;
                axesAspectRatio = axesWidth / axesHeight;

                % Determine the scaling factors to maintain the aspect ratio
                if imageAspectRatio > axesAspectRatio
                    % Image is wider relative to its height than the axes
                    newWidth = axesWidth;
                    newHeight = axesWidth / imageAspectRatio;
                else
                    % Image is taller relative to its width than the axes
                    newHeight = axesHeight;
                    newWidth = axesHeight * imageAspectRatio;
                end

                % Calculate new XData and YData to fit the aspect ratio
                XData = [(axesWidth - newWidth) / 2, (axesWidth + newWidth) / 2];
                YData = [(axesHeight - newHeight) / 2, (axesHeight + newHeight) / 2];

                % Set the new XData and YData
                I.XData = XData;
                I.YData = YData;

                % Update the limits of the axes
                axesHandle.XLim = [XData(1) XData(2)];
                axesHandle.YLim = [YData(1) YData(2)];
            else
                error('Invalid axes handle provided.');
            end
        end


        % Setup Function
        function  applysettings(app,settings)
            % Ensure the settings structure is not empty
            if isempty(settings)
                error('Settings structure is empty.');
            end

            % Apply settings to app properties
            app.SelectSimpleFiltersLabel.Visible = settings('SelectSimpleFiltersLabel');
            app.SimpleImageFiltersDropDown.Enable = settings('SimpleImageFiltersDropDown');
            app.ComplexFiltersLabel.Visible = settings('ComplexFiltersLabel');
            app.Complexfilters.Enable = settings('Complexfilters');

            app.KvalueSlider.Visible = settings('KvalueSliderVisible');
            app.KvalueSliderLabel.Visible = settings('KvalueSliderLabelVisible');
            app.TypesDropDown.Visible = settings('TypesDropDownVisible');
            app.TypesDropDown.Items = settings('TypesDropDownItems');
            app.TypesDropDownLabel.Visible = settings('TypesDropDownLabelVisible');
            app.KvalueSliderLabel.Text = settings('KvalueSliderLabelText');
            app.TypesDropDownLabel.Text = settings('TypesDropDownLabelText');
            app.KvalueSlider.Limits = settings('KvalueSliderLimits');
            app.KvalueSlider.MajorTicks = settings('KvalueSliderMajorTicks');
            app.KvalueSlider.MinorTicks = settings('KvalueSliderMinorTicks');

            %Buttons
            app.SaveImageButton.Visible = settings('SaveImageButtonVisible');
            app.ResetButton.Visible = settings('ResetButton');
        end

        % Apply complex filters settings and reset settings
        function applyComplexFilter(app,mode)

            % Initialize default settings
            defaultSettings = containers.Map(...
                {'SelectSimpleFiltersLabel', 'SimpleImageFiltersDropDown', 'ComplexFiltersLabel', 'Complexfilters', ...
                'KvalueSliderVisible', 'KvalueSliderLabelVisible', 'TypesDropDownVisible', ...
                'TypesDropDownItems', 'TypesDropDownLabelVisible', 'KvalueSliderLabelText', ...
                'TypesDropDownLabelText', 'KvalueSliderLimits', 'KvalueSliderMajorTicks', ...
                'KvalueSliderMinorTicks', 'SaveImageButtonVisible', 'ResetButton'}, ...
                {'on', 'on', 'on', 'on', 'off', 'off', 'off', {}, 'off', '', '', [0, 10], 0:5:10, 0:1:10, 'on', 'on'});

            if nargin < 2
                mode = 'filter'; % Default mode
            end

            if strcmp(mode, 'filter')
                % Define settings based on the selected filter option
                opt = app.Complexfilters.Value;
                settings = defaultSettings; % Start with default settings

                switch opt
                    case "Barrel"
                        settings('KvalueSliderVisible') = 'on';
                        settings('KvalueSliderLabelVisible') = 'on';
                        settings('KvalueSliderLabelText') = 'Barrel Intensity';
                        settings('KvalueSliderLimits') = [0, 10];
                        settings('KvalueSliderMajorTicks') = 0:5:10;
                        settings('KvalueSliderMinorTicks') = 0:1:10;

                    case "Blur"
                        settings('KvalueSliderVisible') = 'off';
                        settings('KvalueSliderLabelVisible') = 'off';
                        settings('TypesDropDownVisible') = 'on';
                        settings('TypesDropDownItems') = {'open', 'close', 'dilate'};
                        settings('TypesDropDownLabelVisible') = 'on';
                        settings('TypesDropDownLabelText') = 'Blur Types';
                    case "Edge"
                        settings('KvalueSliderVisible') = 'off';
                        settings('KvalueSliderLabelVisible') = 'off';
                        settings('TypesDropDownVisible') = 'on';
                        settings('TypesDropDownItems') = {'Prewitt', 'Roberts', 'log', 'zerocross', 'Canny', 'approxcanny'};
                        settings('TypesDropDownLabelVisible') = 'on';
                        settings('TypesDropDownLabelText') = 'Edge Types';
                        opt = "edgeeffect";
                    case "Neon"
                        settings('KvalueSliderVisible') = 'on';
                        settings('KvalueSliderLabelVisible') = 'on';
                        settings('KvalueSliderLabelText') = 'Neon Intensity';
                        settings('KvalueSliderLimits') = [1, 10];
                        settings('KvalueSliderMajorTicks') = 1:2.5:10;
                        settings('KvalueSliderMinorTicks') = 1:0.5:10;
                        settings('TypesDropDownVisible') = 'on';
                        settings('TypesDropDownItems') = {'disk', 'diamond', 'square'};
                        settings('TypesDropDownLabelVisible') = 'off';
                        settings('TypesDropDownLabelText') = 'Neon Types';
                        opt = "neoneffect";
                    case "Pencil"
                        settings('KvalueSliderVisible') = 'off';
                        settings('KvalueSliderLabelVisible') = 'off';
                        settings('TypesDropDownVisible') = 'on';
                        settings('TypesDropDownItems') = {'light', 'dark'};
                        settings('TypesDropDownLabelVisible') = 'on';
                        settings('TypesDropDownLabelText') = 'Pencil Types';
                    case "Pincushion"
                        settings('KvalueSliderVisible') = 'on';
                        settings('KvalueSliderLabelVisible') = 'on';
                        settings('KvalueSliderLabelText') = 'Pincushion Intensity';
                        settings('KvalueSliderLimits') = [0, 25];
                        settings('KvalueSliderMajorTicks') = 0:5:25;
                        settings('KvalueSliderMinorTicks') = 0:5:25;
                    case "Wave"
                        settings('KvalueSliderVisible') = 'on';
                        settings('KvalueSliderLabelVisible') = 'on';
                        settings('KvalueSliderLabelText') = 'Wave Intensity';
                        settings('KvalueSliderLimits') = [0, 360];
                        settings('KvalueSliderMajorTicks') = 0:60:360;
                        settings('KvalueSliderMinorTicks') = 0:30:360;
                    case "Choose"
                        uialert(app.FunhouseFiltersUIFigure, 'Select a Filter', 'No filter chosen');
                        return;
                end

                % Apply the settings
                applysettings(app, settings);

                % Display the image with the selected filter effect
                if strcmp(opt, "Choose")
                    uialert(app.FunhouseFiltersUIFigure, 'Select a Filter', 'No filter chosen');
                else
                    try
                        app.outImg = app.(lower(opt));
                        scaleddisp(app, app.UIAxes2, app.outImg);
                    catch ME
                        disp(ME.message); % Debugging output
                        uialert(app.FunhouseFiltersUIFigure, 'Error', 'Cannot apply the selected filter');
                    end
                end

            elseif strcmp(mode, 'reset')
                % Apply settings for the new reset scenario
                settings = containers.Map(...
                    {'SelectSimpleFiltersLabel', 'SimpleImageFiltersDropDown', 'ComplexFiltersLabel', 'Complexfilters', ...
                    'KvalueSliderVisible', 'KvalueSliderLabelVisible', 'TypesDropDownVisible', ...
                    'TypesDropDownItems', 'TypesDropDownLabelVisible', 'KvalueSliderLabelText', ...
                    'TypesDropDownLabelText', 'KvalueSliderLimits', 'KvalueSliderMajorTicks', ...
                    'KvalueSliderMinorTicks', 'SaveImageButtonVisible', 'ResetButton'}, ...
                    {'on', 'off', 'on', 'off', 'off', 'off', 'off', {}, 'off', '', '', [0, 10], 0:5:10, 0:1:10, 'off', 'on'});

                settings('SelectSimpleFiltersLabel') = 'on';
                settings('SimpleImageFiltersDropDown') = 'off';
                settings('ComplexFiltersLabel') = 'on';
                settings('Complexfilters') = 'off';
                settings('Complexfilters.Value') = 'Choose';
                settings('TakeaPhotoButton.Visible') = 'off';
                settings('TakeaPhotoButton.Enable') = 'on';
                settings('SaveImageButton') = 'off';

                settings('SelectImageSourceDropDown') = 'on';
                settings('SelectImageSourceDropDown.Enable') = 'on';
                settings('KvalueSlider') = 'off';
                settings('KvalueSliderLabel') = 'off';
                settings('TypesDropDown') = 'off';
                settings('TypesDropDownLabel') = 'off';

                % Apply the settings
                applysettings(app, settings);
            else
                error('Invalid mode specified. Use "filter" or "reset".');
            end
        end

        % Wave Function
        function outImg = wave(app)
            a1 = app.KvalueSlider.Value; % Try varying the amplitude of the sinusoids.
            a2 = 25;

            [nrows,ncols,~] = size(app.inImg);
            [xi,yi] = meshgrid(1:ncols,1:nrows);
            imid = round(size(app.inImg,2)/2); % Find index of middle element

            u = xi + a1*sin(pi*xi/imid);
            v = yi - a2*sin(pi*yi/imid);
            tmap_B = cat(3,u,v);
            resamp = makeresampler('linear','fill');
            outImg = tformarray(app.inImg,[],resamp,[2 1],[1 2],[],tmap_B,.3);


        end


        function captureFrames(app)
            try
                % % check if app is closing
                % if app.isClosing
                %     % closeApp(app)
                % end
                % check if webcam object is valid
                if ~isvalid(app.cam)
                    error('Webcam object is invalid or deleted.')
                end
                 
                frame = fliplr(snapshot(app.cam)); % Capture a frame
                       app.img = frame;

                       % Update UIAxes with the new frame
                       if ishandle(app.UIAxes2)
                           % Call scaleddisp with the appropriate parameters
                           scaleddisp(app, app.UIAxes, app.img); % Pass empty for the first argument
                       else
                           stop(app.photoTimer); % Stop the timer if UIAxes is no longer valid
                           delete(app.photoTimer); % Clean up the timer object
                       end
            catch ME
                % closeApp(app)
            end


            % Make filters visible
            app.SimpleImageFiltersDropDown.Enable = "on";
            app.SelectSimpleFiltersLabel.Enable = "on";
            app.Complexfilters.Visible = "on";
            app.ComplexFiltersLabel.Visible = "on";

        end


        function  stopCapture(app)
            % Ensure the timer is stopped and deleted if the button is disabled
            if strcmp(app.TakeaPhotoButton.Enable, "off")
                if isvalid(app.photoTimer)
                    stop(app.photoTimer);
                    delete(app.photoTimer);
                    app.photoTimer = []; % Clear the reference for safety
                end
            end
        end

        function closeApp(app)
            app.isClosing = true; % Set the flag
            if isvalid(app.photoTimer)
                stop(app.photoTimer);
                delete(app.photoTimer);
            end
            % Optionally, clean up the webcam object
            if isvalid(app.cam)
                clear app.cam;
            end
            delete(app.UIFigure); % Close the app

        end
    end


    methods (Access = public)

        function outImg = neoneffect(app,~)

            a = round(app.KvalueSlider.Value);
            s = app.TypesDropDown.Value;
            se = strel(s,a);
            outImg = imsubtract(imdilate(app.inImg,se),imerode(app.inImg,se));
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Move app to center of screen
            % movegui(app.FunhouseFiltersUIFigure,'center');

            % Get the list of available webcams
            app.wlist = webcamlist;
            items = ["Choose"; string(app.wlist); "Select your own Image"];


            % Update the dropdown menu
            set(app.SelectImageSourceDropDown, 'Items', items);


            %Disable buttons and sliders
            app.TakeaPhotoButton.Visible = "off";
            app.TakeaPhotoButton.Enable = "off";
            app.SimpleImageFiltersDropDown.Enable = "off";
            app.Complexfilters.Enable = "off";
            app.SaveImageButton.Visible = "off";

        end

        % Value changed function: SelectImageSourceDropDown
        function SelectImageSourceDropDownValueChanged(app, event)
            % Retrieve the selected value from dropdown
            selectedValue = app.SelectImageSourceDropDown.Value;

            % Make filter options visible and enabled
            app.SimpleImageFiltersDropDown.Visible = "on";
            app.Complexfilters.Visible = "on";
            app.SimpleImageFiltersDropDown.Enable = "on";
            app.Complexfilters.Enable = "on";
            app.SelectSimpleFiltersLabel.Visible = "on";
            app.ComplexFiltersLabel.Visible = "on";

            switch selectedValue
                case "Select your own Image"
                    % Hide photo options
                    app.TakeaPhotoButton.Visible = "off";


                    % Open file selection dialog
                    [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tif', 'Image Files (*.png, *.jpg, *.jpeg, *.bmp, *.tif)'}, 'Select an Image');

                    % Attempt to read the image
                    if isequal(file,0) || isequal(path,0)
                        return; % User cancelled file selection
                    end

                    try
                        fullImagePath = fullfile(path, file);
                        app.img = imread(fullImagePath);
                        scaleddisp(app, app.UIAxes, app.img);
                    catch
                        uialert(app.FunhouseFiltersUIFigure, "Cannot read image. Please select another image.", "Warning");
                    end

                % case {app.imlabels{:}}
                %     % Hide photo button
                %     app.TakeaPhotoButton.Enable = "off";
                %     app.TakeaPhotoButton.Visible = "off";
                % 
                % 
                %     % Find and read the corresponding image
                %     imIndex = strcmp(selectedValue, app.imlabels);
                %     if any(imIndex)
                %         app.img = imread(app.imagefiles{imIndex});
                %         scaleddisp(app, app.UIAxes, app.img);
                %     end

                case {app.wlist{:}}
                    % Show and enable take a photo button
                    app.TakeaPhotoButton.Enable = "on";
                    app.TakeaPhotoButton.Visible = "on";


                    % Handle webcam selection
                    camIndex = strcmp(selectedValue, app.wlist);
                    if any(camIndex)
                        camIndex = find(camIndex);
                        app.cam = webcam(camIndex);

                        % Create and configure the timer object
                        app.photoTimer = timer;

                        % Set timer properties
                        app.photoTimer.Period = 0.1; % Set the interval for updates
                        app.photoTimer.ExecutionMode = 'fixedSpacing';
                        app.photoTimer.TimerFcn = @(~, ~) captureFrames(app);

                        % Start the timer when the photo button is enabled
                        if strcmp(app.TakeaPhotoButton.Enable, "on")
                            start(app.photoTimer);
                        end

                    end
            end

            % Reset dropdown value and disable it
            app.SelectImageSourceDropDown.Value = "Choose";
            app.SelectImageSourceDropDown.Enable = "off";
            figure(app.FunhouseFiltersUIFigure);
            app.inImg = app.img;
            

        end

        % Button pushed function: TakeaPhotoButton
        function TakeaPhotoButtonPushed(app, event)
 
app.photoTaken = true; % Mark that the photo has been taken

%  Stop the timer
if isvalid(app.photoTimer)
    stop(app.photoTimer);
end

% Acquire webcam image
app.img = fliplr(snapshot(app.cam));

% Show acquired image
scaleddisp(app, app.UIAxes2, app.img);
clear('app.cam');

% Disable Take a Photo Button
app.TakeaPhotoButton.Enable = "off";
app.TakeaPhotoButton.Text="Photo Taken";
app.SelectImageSourceDropDown.Enable = "off";


% Make filters visible
app.SimpleImageFiltersDropDown.Enable = "on";
app.SelectSimpleFiltersLabel.Enable = "on";
app.Complexfilters.Visible = "on";
app.ComplexFiltersLabel.Visible = "on";

        end

        % Value changed function: SimpleImageFiltersDropDown
        function SimpleImageFiltersDropDownOpening(app, event)
            app.KvalueSlider.Visible = false;
            app.KvalueSliderLabel.Visible = false;
            app.KvalueSliderLabel.Text = "K value";
            app.TypesDropDown.Visible = false;
            app.TypesDropDownLabel.Visible = false;
            app.TypesDropDownLabel.Text = "Edge Types";
            
           
            app.Complexfilters.Value = "Choose";
            app.inImg = app.img;
            opt = app.SimpleImageFiltersDropDown.Value;
            if opt == "Choose"
            else

                switch opt
                    case "Flip Left - Right"
                        app.outImg =fliplr(app.inImg);
                    case "Flip Up - Down"
                        app.outImg = flipud(app.inImg);
                    case "Grayscale"
                        app.outImg = rgb2gray(app.inImg);
                    case "Invert"
                        app.outImg = imcomplement(app.inImg);

                end


            end

            % Display Image
            scaleddisp(app,app.UIAxes2,app.outImg);

            % Enable Save Button
            app.SaveImageButton.Visible = "on";
            app.SaveImageButton.Enable = "on";

            % Set the dropdown value back to default
            app.SimpleImageFiltersDropDown.Value = "Choose";
        end

        % Value changed function: Complexfilters, KvalueSlider, 
        % ...and 1 other component
        function ComplexfiltersValueChanged(app, event)


            app.inImg = app.img;

            applyComplexFilter(app,'filter')
            % Ensure the final image is displayed
            scaleddisp(app, app.UIAxes2, app.outImg);

            % Enable Save Button
            app.SaveImageButton.Visible = "on";
            app.SaveImageButton.Enable = "on";


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %Restore original app settings
            applyComplexFilter(app,'reset')

            app.SelectImageSourceDropDown.Visible ="on";
            app.SelectImageSourceDropDown.Enable ="on";


            cla(app.UIAxes, "reset");
            app.UIAxes.BoxStyle = "full";
            app.UIAxes.Box = "on";
            app.UIAxes.Clipping = "on";
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = [];
            cla(app.UIAxes2, "reset");
            app.UIAxes2.BoxStyle = "full";
            app.UIAxes2.Box = "on";
            app.UIAxes2.Clipping = "on";
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = [];
            app.TakeaPhotoButton.Visible = "off";
            app.TakeaPhotoButton.Text="Take a Photo";
                % Enable Save Button
            app.SaveImageButton.Visible = "off";
            app.SaveImageButton.Enable = "off";

        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)

            % Prompt the user to select a file name and folder
            [file, path] = uiputfile({'*.png'; '*.jpg'; '*.jpeg'; '*.bmp'; '*.tiff'}, ...
                'Save Image As');

            % Check if the user canceled the dialog
            if isequal(file, 0) || isequal(path, 0)
                % User canceled the file dialog
                return;
            end

            % Construct the full file path
            fullPath = fullfile(path, file);

            % Ensure the image data is available
            if isprop(app,'outImg')
                % Save the image
                try
                    imwrite(app.outImg, fullPath);
                    % Notify the user
                    uialert(app.FunhouseFiltersUIFigure, ['Image saved to ' fullPath], 'Save Successful');
                catch ME
                    % Handle any errors that occur during saving
                    uialert(app.FunhouseFiltersUIFigure, ['Failed to save image: ' ME.message], 'Save Error');
                end
            else
                % Notify the user if no image is available
                uialert(app.FunhouseFiltersUIFigure, 'No image available to save.', 'Save Error');
            end
            figure(app.FunhouseFiltersUIFigure)
        end

        % Value changing function: KvalueSlider
        function KvalueSliderValueChanging(app, event)
            changingValue = event.Value;
            % Reapply the complex filter settings based on current mode or state
            applyComplexFilter(app, 'filter');
        end

        % Drop down opening function: TypesDropDown
        function TypesDropDownOpening(app, event)
            % Reapply the complex filter settings based on current mode or state
            applyComplexFilter(app, 'filter');
        end

        % Close request function: FunhouseFiltersUIFigure
        function FunhouseFiltersUIFigureCloseRequest(app, event)
          
               app.isClosing = true; % Set the flag
            if isvalid(app.photoTimer)
                stop(app.photoTimer);
                delete(app.photoTimer);
            end
            % Optionally, clean up the webcam object
            if isvalid(app.cam)
                clear app.cam;
            end
            delete(app.FunhouseFiltersUIFigure); % Close the app
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FunhouseFiltersUIFigure = uifigure('Visible', 'off');
            app.FunhouseFiltersUIFigure.Name = 'Funhouse Filters';
            app.FunhouseFiltersUIFigure.CloseRequestFcn = createCallbackFcn(app, @FunhouseFiltersUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.FunhouseFiltersUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.GridLayout);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.TakeaPhotoButton = uibutton(app.GridLayout, 'push');
            app.TakeaPhotoButton.ButtonPushedFcn = createCallbackFcn(app, @TakeaPhotoButtonPushed, true);
            app.TakeaPhotoButton.Text = 'Take a Photo';
            app.SimpleImageFiltersDropDown = uidropdown(app.GridLayout);
            app.SimpleImageFiltersDropDown.Items = {'Choose', 'Flip Left - Right', 'Flip Up - Down', 'Grayscale', 'Invert'};
            app.SimpleImageFiltersDropDown.ValueChangedFcn = createCallbackFcn(app, @SimpleImageFiltersDropDownOpening, true);
            app.SimpleImageFiltersDropDown.Value = 'Choose';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SelectSimpleFiltersLabel = uilabel(app.GridLayout);
            app.SelectSimpleFiltersLabel.Text = 'Select Simple Filters';
            app.SaveImageButton = uibutton(app.GridLayout, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.KvalueSliderLabel = uilabel(app.GridLayout);
            app.KvalueSliderLabel.Visible = 'off';
            app.KvalueSliderLabel.Text = 'K value';
            app.KvalueSlider = uislider(app.GridLayout);
            app.KvalueSlider.Limits = [0 10];
            app.KvalueSlider.MajorTicks = [0 2 4 6 8 10];
            app.KvalueSlider.ValueChangedFcn = createCallbackFcn(app, @ComplexfiltersValueChanged, true);
            app.KvalueSlider.ValueChangingFcn = createCallbackFcn(app, @KvalueSliderValueChanging, true);
            app.KvalueSlider.MinorTicks = [0 0.4 0.8 1.2 1.6 2 2.4 2.8 3.2 3.6 4 4.4 4.8 5.2 5.6 6 6.4 6.8 7.2 7.6 8 8.4 8.8 9.2 9.6 10];
            app.KvalueSlider.Visible = 'off';
            app.TypesDropDownLabel = uilabel(app.GridLayout);
            app.TypesDropDownLabel.Visible = 'off';
            app.TypesDropDownLabel.Text = 'Types';
            app.TypesDropDown = uidropdown(app.GridLayout);
            app.TypesDropDown.Items = {'Choose', 'Open', 'Close', 'Dilate'};
            app.TypesDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TypesDropDownOpening, true);
            app.TypesDropDown.ValueChangedFcn = createCallbackFcn(app, @ComplexfiltersValueChanged, true);
            app.TypesDropDown.Visible = 'off';
            app.TypesDropDown.Value = 'Choose';
            app.ComplexFiltersLabel = uilabel(app.GridLayout);
            app.ComplexFiltersLabel.Text = 'Select Complex Filters';
            app.Complexfilters = uidropdown(app.GridLayout);
            app.Complexfilters.Items = {'Choose', 'Barrel', 'Blur', 'Edge', 'Neon', 'Pencil', 'Pincushion', 'Wave'};
            app.Complexfilters.ValueChangedFcn = createCallbackFcn(app, @ComplexfiltersValueChanged, true);
            app.Complexfilters.Value = 'Choose';
            app.SelectImageSourceDropDownLabel = uilabel(app.GridLayout);
            app.SelectImageSourceDropDownLabel.Text = 'Select Image Source';
            app.SelectImageSourceDropDown = uidropdown(app.GridLayout);
            app.SelectImageSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectImageSourceDropDownValueChanged, true);
            % Show the figure after all components are created
            app.FunhouseFiltersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Funhouse_Filters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FunhouseFiltersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FunhouseFiltersUIFigure)
        end
    end
end